classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        Tab                      matlab.ui.container.Tab
        LoadleftImageButton      matlab.ui.control.Button
        LoadrightImageButton     matlab.ui.control.Button
        RUNButton                matlab.ui.control.Button
        Button4                  matlab.ui.control.Button
        DisplacementSliderLabel  matlab.ui.control.Label
        DisplacementSlider       matlab.ui.control.Slider
        ScalingSliderLabel       matlab.ui.control.Label
        ScalingSlider            matlab.ui.control.Slider
        UIAxes                   matlab.ui.control.UIAxes
        Tab2                     matlab.ui.container.Tab
    end


    properties (Access = public)
        %Property % Description
        Image_L;
        Image_R;
        Scaling = 1;
        p = 0.5;
        halfBolcksize = 0;
        disparityRange = 400;
        K;
    end


    methods (Access = private)

        % Button pushed function: LoadleftImageButton
        function LoadleftImageButtonPushed(app, event)
            [file,path] = uigetfile({'*.jpg','*.png'}, 'Select an image');
            app.Image_L = imread(fullfile(path,file));
   
        end

        % Button pushed function: LoadrightImageButton
        function LoadrightImageButtonPushed(app, event)
            [file,path] = uigetfile({'*.jpg','*.png'}, 'Select an image');
            app.Image_R = imread(fullfile(path,file));
            
            %set(app.UIAxes,'Units','pixels');
            %resizePos = get(app.UIAxes,'Position');
            %Image_R= imresize(Image_R, [resizePos(3) resizePos(3)]);
            %axes(app.UIAxes);

            %set(app.UIAxes,'Units','normalized');
        end

        % Value changed function: DisplacementSlider
        function DisplacementSliderValueChanged(app, event)
            p = app.DisplacementSlider.Value;
            
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            %%check inputs
            load 'img/rect_im_L2.mat' Rectification_image1;
            load 'img/rect_im_R2.mat' Rectification_image2;
            Image_L_rec=Rectification_image1;
            Image_R_rec=Rectification_image2;
      
            app.output_image=free_viewpoint(imresize(Image_L_rec, app.Scaling), ...
                                        imresize(Image_R_rec, app.Scaling), ...
                                        imresize(app.Image_L,app.Scaling), ...
                                        imresize(app.Image_R,app.Scaling), ...
                                        app.p, app.K, app.halfBolcksize, app.disparityRange*app.Scaling);
            app.output_image=imresize(app.output_image,1/app.Scaling);

        end

        % Value changed function: ScalingSlider
        function ScalingSliderValueChanged(app, event)
            Scaling = app.ScalingSlider.Value;

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.LoadleftImageButton = uibutton(app.Tab, 'push');
            app.LoadleftImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadleftImageButtonPushed, true);
            app.LoadleftImageButton.Text = 'Load left Image';
            app.LoadrightImageButton = uibutton(app.Tab, 'push');
            app.LoadrightImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadrightImageButtonPushed, true);
            app.LoadrightImageButton.Text = 'Load right Image';
            app.RUNButton = uibutton(app.Tab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.Button4 = uibutton(app.Tab, 'push');
            app.Button4.Text = 'Button4';
            app.DisplacementSliderLabel = uilabel(app.Tab);
            app.DisplacementSliderLabel.Text = 'Displacement';
            app.DisplacementSlider = uislider(app.Tab);
            app.DisplacementSlider.Limits = [0 1];
            app.DisplacementSlider.ValueChangedFcn = createCallbackFcn(app, @DisplacementSliderValueChanged, true);
            app.DisplacementSlider.Value = 0.5;
            app.ScalingSliderLabel = uilabel(app.Tab);
            app.ScalingSliderLabel.Text = 'Scaling';
            app.ScalingSlider = uislider(app.Tab);
            app.ScalingSlider.Limits = [0 1];
            app.ScalingSlider.ValueChangedFcn = createCallbackFcn(app, @ScalingSliderValueChanged, true);
            app.ScalingSlider.Value = 1;
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Left')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end